classdef Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SMASH            matlab.ui.Figure
        TabGroup         matlab.ui.container.TabGroup
        Sport            matlab.ui.container.Tab
        uploadsport      matlab.ui.control.Button
        sportpanel       matlab.ui.container.Panel
        PlasticGoodsTab  matlab.ui.container.Tab
        uploadplastic    matlab.ui.control.Button
        plasticpanel     matlab.ui.container.Panel
        ToiletItemsTab   matlab.ui.container.Tab
        uploadtoilet     matlab.ui.control.Button
        toiletpanel      matlab.ui.container.Panel
        TotalCostLabel   matlab.ui.control.Label
        Totalprice       matlab.ui.control.Label
        NoofItemsLabel   matlab.ui.control.Label
        totalnum         matlab.ui.control.Label
    end

    
    properties (Access = public)
%         allitems={}
        sportitems={}
        plasticitems={}
        toiletitems={}
        nums=0
        nump=0
        numt=0
        Dialogbox
        net={}
        labellist
        pricelist
    end
    
    methods (Access = public)
        
        function uploadimage(app,i,n,k)
            label=test(i,app.net{k});
            label=string(label);
            j=myobject;
            price=app.pricelist(app.labellist==label);
            if k==1
                app.nums=app.nums+1;
                j=j.createme(app.sportpanel,label,[n price],@app.calculate);
                j.setpos(app.nums-1);
                app.sportitems{app.nums}=j;
            elseif k==2
                app.nump=app.nump+1;
                j=j.createme(app.plasticpanel,label,[n price],@app.calculate);
                j.setpos(app.nump-1);
                app.plasticitems{app.nump}=j;
            else
                if strcmp(label,'Handwash')
                    label=test(i,app.net{5});
                    label=string(label);
                    price=app.pricelist(app.labellist==label);
                elseif strcmp(label,'Soap')
                    label=test(i,app.net{4});
                    label=string(label);                    
                    price=app.pricelist(app.labellist==label);
                elseif strcmp(label,'Oil')
                    label=test(i,app.net{6});
                    label=string(label);                    
                    price=app.pricelist(app.labellist==label);
                else
                    label=test(i,app.net{7});
                    label=string(label);                    
                    if strcmp(label,'ToothBrush')
                        price=app.pricelist(app.labellist==label);
                    else 
                        label=test(i,app.net{8});
                        label=string(label);                        
                        price=app.pricelist(app.labellist==label);
                    end                                      
                end
                app.numt=app.numt+1;
                j=j.createme(app.toiletpanel,label,[n price],@app.calculate);
                j.setpos(app.numt-1);
                app.toiletitems{app.numt}=j;  
            end           
%             app.allitems{app.nums+app.nump+app.numd}=j;
            app.calculate();
        end
        
        function loading(app)
            [app.pricelist,app.labellist,~]=xlsread('pricelist.xlsx');
            app.labellist=string(app.labellist);
            load('mainworkspace.mat')
            app.net{1}=SportNet;
            app.net{2}=PlasticNet;
            app.net{3}=ToiletNet;
            app.net{4}=SoapNet;
            app.net{5}=HandNet;
            app.net{6}=OilNet;
            app.net{7}=ToothbpNet;
            app.net{8}=ToothpasteNet;
        end
        
        function calculate(app)
            c=0;
            for i=1:length(app.sportitems)
                c=c+str2double(app.sportitems{i}.cost.Text);              
            end
            for i=1:length(app.plasticitems)
                c=c+str2double(app.sportitems{i}.cost.Text);              
            end
            for i=1:length(app.toiletitems)
                c=c+str2double(app.sportitems{i}.cost.Text);              
            end         
            app.Totalprice.Text=int2str(c);
            app.totalnum.Text=int2str(app.numt+app.nump+app.nums);            
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SMASH.Name='SMASH';
            app.loading()
            app.plasticpanel.BorderType='none';
            app.sportpanel.BorderType='none';
            app.toiletpanel.BorderType='none';
        end

        % Button pushed function: uploadsport
        function uploadsportButtonPushed(app, event)
            app.Dialogbox=secondary(app,1);
        end

        % Close request function: SMASH
        function SMASHCloseRequest(app, event)
            delete(app)
%             app.Dialogbox.delete();
        end

        % Button pushed function: uploadplastic
        function uploadplasticButtonPushed(app, event)
            app.Dialogbox=secondary(app,2);            
        end

        % Button pushed function: uploadtoilet
        function uploadtoiletButtonPushed(app, event)
            app.Dialogbox=secondary(app,3);           
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SMASH = uifigure;
            app.SMASH.Name = 'UI Figure';
            app.SMASH.CloseRequestFcn = createCallbackFcn(app, @SMASHCloseRequest, true);
            app.TabGroup = uitabgroup(app.SMASH);
            app.Sport = uitab(app.TabGroup);
            app.Sport.Title = 'Sport';
            app.uploadsport = uibutton(app.Sport, 'push');
            app.uploadsport.ButtonPushedFcn = createCallbackFcn(app, @uploadsportButtonPushed, true);
            app.uploadsport.Icon = 'upload_in_mainapp.jpg';
            app.uploadsport.Text = 'Upload';
            app.sportpanel = uipanel(app.Sport);
            app.PlasticGoodsTab = uitab(app.TabGroup);
            app.PlasticGoodsTab.Title = 'Plastic Goods';
            app.uploadplastic = uibutton(app.PlasticGoodsTab, 'push');
            app.uploadplastic.ButtonPushedFcn = createCallbackFcn(app, @uploadplasticButtonPushed, true);
            app.uploadplastic.Icon = 'upload_in_mainapp.jpg';
            app.uploadplastic.Text = 'Upload';
            app.plasticpanel = uipanel(app.PlasticGoodsTab);
            app.ToiletItemsTab = uitab(app.TabGroup);
            app.ToiletItemsTab.Title = 'Toilet Items';
            app.uploadtoilet = uibutton(app.ToiletItemsTab, 'push');
            app.uploadtoilet.ButtonPushedFcn = createCallbackFcn(app, @uploadtoiletButtonPushed, true);
            app.uploadtoilet.Icon = 'upload_in_mainapp.jpg';
            app.uploadtoilet.Text = 'Upload';
            app.toiletpanel = uipanel(app.ToiletItemsTab);
            app.TotalCostLabel = uilabel(app.SMASH);
            app.TotalCostLabel.Text = 'Total  Cost     :';
            app.Totalprice = uilabel(app.SMASH);
            app.Totalprice.Text = '0';
            app.NoofItemsLabel = uilabel(app.SMASH);
            app.NoofItemsLabel.Text = 'No.of Items     :';
            app.totalnum = uilabel(app.SMASH);
            app.totalnum.Text = '0';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Main

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SMASH)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SMASH)
        end
    end
end